classdef pra1_App_2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1            matlab.ui.Figure
        Menujrb            matlab.ui.container.Menu
        Submenu1jrb        matlab.ui.container.Menu
        Submenu2jrb        matlab.ui.container.Menu
        Submenu3jrb        matlab.ui.container.Menu
        Submenu4jrb        matlab.ui.container.Menu
        Submenu5jrb        matlab.ui.container.Menu
        Submenu6jrb        matlab.ui.container.Menu
        Submenu7jrb        matlab.ui.container.Menu
        Submenu8jrb        matlab.ui.container.Menu
        Submenu9jrb        matlab.ui.container.Menu
        Submenu10jrb       matlab.ui.container.Menu
        Submenu11jrb       matlab.ui.container.Menu
        Submenu12jrb       matlab.ui.container.Menu
        MartinMorenoCesarSadrakTelefona2TM9Label  matlab.ui.control.Label
        EspectogramaPanel  matlab.ui.container.Panel
        espectograma       matlab.ui.control.UIAxes
        OscilogramaPanel   matlab.ui.container.Panel
        oscilograma        matlab.ui.control.UIAxes
        TecladoPanel       matlab.ui.container.Panel
        llamada_8          matlab.ui.control.Button
        tecla4             matlab.ui.control.Button
        tecla2             matlab.ui.control.Button
        teclaGato          matlab.ui.control.Button
        tecla0             matlab.ui.control.Button
        teclaAst           matlab.ui.control.Button
        tecla9             matlab.ui.control.Button
        tecla8             matlab.ui.control.Button
        tecla7             matlab.ui.control.Button
        tecla6             matlab.ui.control.Button
        tecla5             matlab.ui.control.Button
        tecla3             matlab.ui.control.Button
        tecla1             matlab.ui.control.Button
        llamada            matlab.ui.control.Button
        llamada_2          matlab.ui.control.Button
        llamada_3          matlab.ui.control.Button
        llamada_4          matlab.ui.control.Button
        llamada_5          matlab.ui.control.Button
        llamada_6          matlab.ui.control.Button
        llamada_7          matlab.ui.control.Button
        Image              matlab.ui.control.Image
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function pra1_OpeningFcn(app, varargin)
            % Ensure that the app appears on screen when run
            movegui(app.figure1, 'onscreen');
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to pra1 (see VARARGIN)
            
            % Choose default command line output for pra1
            handles.output = hObject;
            
            % Update handles structure
            guidata(hObject, handles);
            
            % UIWAIT makes pra1 wait for user response (see UIRESUME)
            % uiwait(handles.figure1);
           
        end

        % Menu selected function: Submenu10jrb
        function Submenu10jrb_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to Submenu10jrb (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            filaf = [697,770,852,941];
               coluf = [1209,1336,1447];
            
                 fs=8000; %frecuencia de muestreo (operación -->gráfica y sound)
                 tn=[0:1/fs:0.8]; %vector de tiempo para las señales numéricas
            
                %Definimos la señal de cada dígito
            
                % tecla dos
                s1 = 0.5*[sin(2*pi*filaf(4)*tn)+sin(2*pi*coluf(1)*tn)];
                % oscilograma
                %gráfica en el dominio del tiempo
                axes(handles.oscilograma);% etiqueta del primer eje
                cla;
                plot(tn,s1/max(s1));  % señal normalizada
                title('Oscilograma de la señal numérica al teclear "2"'),xlabel('t(s)'),ylabel('Suma de senos de 697 Hz y 1336 Hz');
                % espectograma
                    %transformada de fourier
                    ffts1 = fftshift(fft(s1));
                    %eje de frecuencias
                    frec = linspace(-fs/2,fs/2,length(ffts1));
                %gráfica en el dominio de la frecuencia
                axes(handles.espectograma); % etiqueta del segundo eje
                cla;
                plot(frec,abs((ffts1)/max(ffts1))); %espectro normalizado
                title('Espectro de la señal numérica al teclear "2"'),xlabel('f(Hz)'),ylabel('Espectro de la señal');
                sound(s1,fs);
        end

        % Menu selected function: Submenu11jrb
        function Submenu11jrb_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to Submenu11jrb (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            filaf = [697,770,852,941];
               coluf = [1209,1336,1447];
            
                 fs=8000; %frecuencia de muestreo (operación -->gráfica y sound)
                 tn=[0:1/fs:0.8]; %vector de tiempo para las señales numéricas
            
                %Definimos la señal de cada dígito
            
                % tecla dos
                s1 = 0.5*[sin(2*pi*filaf(4)*tn)+sin(2*pi*coluf(2)*tn)];
                % oscilograma
                %gráfica en el dominio del tiempo
                axes(handles.oscilograma);% etiqueta del primer eje
                cla;
                plot(tn,s1/max(s1));  % señal normalizada
                title('Oscilograma de la señal numérica al teclear "2"'),xlabel('t(s)'),ylabel('Suma de senos de 697 Hz y 1336 Hz');
                % espectograma
                    %transformada de fourier
                    ffts1 = fftshift(fft(s1));
                    %eje de frecuencias
                    frec = linspace(-fs/2,fs/2,length(ffts1));
                %gráfica en el dominio de la frecuencia
                axes(handles.espectograma); % etiqueta del segundo eje
                cla;
                plot(frec,abs((ffts1)/max(ffts1))); %espectro normalizado
                title('Espectro de la señal numérica al teclear "2"'),xlabel('f(Hz)'),ylabel('Espectro de la señal');
                sound(s1,fs);
        end

        % Menu selected function: Submenu12jrb
        function Submenu12jrb_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to Submenu12jrb (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            filaf = [697,770,852,941];
               coluf = [1209,1336,1447];
            
                 fs=8000; %frecuencia de muestreo (operación -->gráfica y sound)
                 tn=[0:1/fs:0.8]; %vector de tiempo para las señales numéricas
            
                %Definimos la señal de cada dígito
            
                % tecla dos
                s1 = 0.5*[sin(2*pi*filaf(4)*tn)+sin(2*pi*coluf(3)*tn)];
                % oscilograma
                %gráfica en el dominio del tiempo
                axes(handles.oscilograma);% etiqueta del primer eje
                cla;
                plot(tn,s1/max(s1));  % señal normalizada
                title('Oscilograma de la señal numérica al teclear "2"'),xlabel('t(s)'),ylabel('Suma de senos de 697 Hz y 1336 Hz');
                % espectograma
                    %transformada de fourier
                    ffts1 = fftshift(fft(s1));
                    %eje de frecuencias
                    frec = linspace(-fs/2,fs/2,length(ffts1));
                %gráfica en el dominio de la frecuencia
                axes(handles.espectograma); % etiqueta del segundo eje
                cla;
                plot(frec,abs((ffts1)/max(ffts1))); %espectro normalizado
                title('Espectro de la señal numérica al teclear "2"'),xlabel('f(Hz)'),ylabel('Espectro de la señal');
                sound(s1,fs);
        end

        % Menu selected function: Submenu1jrb
        function Submenu1jrb_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to Submenu1jrb (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            filaf = [697,770,852,941];
               coluf = [1209,1336,1447];
            
                 fs=2300; %frecuencia de muestreo (operación -->gráfica y sound)
                 tn=[0:1/fs:1]; %vector de tiempo para las señales numéricas
            
                %Se define la señal de cada dígito
            
                % tecla uno
                s1 = 0.5*[sin(2*pi*filaf(1)*tn)+sin(2*pi*coluf(1)*tn)];
                % oscilograma
                %gráfica en el dominio del tiempo
                axes(handles.oscilograma); % etiqueta del primer eje
                cla;
                plot(tn,s1/max(s1));  % señal normalizada
                title('Oscilograma de la señal numérica al teclear "1"'),xlabel('t(s)'),ylabel('Suma de senos de 697 Hz y 1209 Hz');
                % espectograma
                    %transformada de fourier
                    ffts1 = fftshift(fft(s1));
                    %eje de frecuencias
                    frec = linspace(-fs/2,fs/2,length(ffts1));
                %gráfica en el dominio de la frecuencia
                axes(handles.espectograma); % etiqueta del segundo eje
                cla;
                plot(frec,abs((ffts1)/max(ffts1))); %espectro normalizado
                title('Espectro de la señal numérica al teclear "1"'),xlabel('f(Hz)'),ylabel('Espectro de la señal');
                sound(s1,fs);
        end

        % Menu selected function: Submenu2jrb
        function Submenu2jrb_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to Submenu2jrb (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            filaf = [697,770,852,941];
               coluf = [1209,1336,1447];
            
                 fs=8000; %frecuencia de muestreo (operación -->gráfica y sound)
                 tn=[0:1/fs:0.8]; %vector de tiempo para las señales numéricas
            
                %Definimos la señal de cada dígito
            
                % tecla dos
                s1 = 0.5*[sin(2*pi*filaf(1)*tn)+sin(2*pi*coluf(2)*tn)];
                % oscilograma
                %gráfica en el dominio del tiempo
                axes(handles.oscilograma);% etiqueta del primer eje
                cla;
                plot(tn,s1/max(s1));  % señal normalizada
                title('Oscilograma de la señal numérica al teclear "2"'),xlabel('t(s)'),ylabel('Suma de senos de 697 Hz y 1336 Hz');
                % espectograma
                    %transformada de fourier
                    ffts1 = fftshift(fft(s1));
                    %eje de frecuencias
                    frec = linspace(-fs/2,fs/2,length(ffts1));
                %gráfica en el dominio de la frecuencia
                axes(handles.espectograma); % etiqueta del segundo eje
                cla;
                plot(frec,abs((ffts1)/max(ffts1))); %espectro normalizado
                title('Espectro de la señal numérica al teclear "2"'),xlabel('f(Hz)'),ylabel('Espectro de la señal');
                sound(s1,fs);
        end

        % Menu selected function: Submenu3jrb
        function Submenu3jrb_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to Submenu3jrb (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            filaf = [697,770,852,941];
               coluf = [1209,1336,1447];
            
                 fs=8000; %frecuencia de muestreo (operación -->gráfica y sound)
                 tn=[0:1/fs:0.8]; %vector de tiempo para las señales numéricas
            
                %Definimos la señal de cada dígito
            
                % tecla dos
                s1 = 0.5*[sin(2*pi*filaf(1)*tn)+sin(2*pi*coluf(3)*tn)];
                % oscilograma
                %gráfica en el dominio del tiempo
                axes(handles.oscilograma);% etiqueta del primer eje
                cla;
                plot(tn,s1/max(s1));  % señal normalizada
                title('Oscilograma de la señal numérica al teclear "3"'),xlabel('t(s)'),ylabel('Suma de senos de 697 Hz y 1336 Hz');
                % espectograma
                    %transformada de fourier
                    ffts1 = fftshift(fft(s1));
                    %eje de frecuencias
                    frec = linspace(-fs/2,fs/2,length(ffts1));
                %gráfica en el dominio de la frecuencia
                axes(handles.espectograma); % etiqueta del segundo eje
                cla;
                plot(frec,abs((ffts1)/max(ffts1))); %espectro normalizado
                title('Espectro de la señal numérica al teclear "3"'),xlabel('f(Hz)'),ylabel('Espectro de la señal');
                sound(s1,fs);
        end

        % Menu selected function: Submenu4jrb
        function Submenu4jrb_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to Submenu4jrb (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            filaf = [697,770,852,941];
               coluf = [1209,1336,1447];
            
                 fs=8000; %frecuencia de muestreo (operación -->gráfica y sound)
                 tn=[0:1/fs:0.8]; %vector de tiempo para las señales numéricas
            
                %Definimos la señal de cada dígito
            
                % tecla dos
                s1 = 0.5*[sin(2*pi*filaf(2)*tn)+sin(2*pi*coluf(1)*tn)];
                % oscilograma
                %gráfica en el dominio del tiempo
                axes(handles.oscilograma);% etiqueta del primer eje
                cla;
                plot(tn,s1/max(s1));  % señal normalizada
                title('Oscilograma de la señal numérica al teclear "2"'),xlabel('t(s)'),ylabel('Suma de senos de 697 Hz y 1336 Hz');
                % espectograma
                    %transformada de fourier
                    ffts1 = fftshift(fft(s1));
                    %eje de frecuencias
                    frec = linspace(-fs/2,fs/2,length(ffts1));
                %gráfica en el dominio de la frecuencia
                axes(handles.espectograma); % etiqueta del segundo eje
                cla;
                plot(frec,abs((ffts1)/max(ffts1))); %espectro normalizado
                title('Espectro de la señal numérica al teclear "2"'),xlabel('f(Hz)'),ylabel('Espectro de la señal');
                sound(s1,fs);
        end

        % Menu selected function: Submenu5jrb
        function Submenu5jrb_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to Submenu5jrb (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            filaf = [697,770,852,941];
               coluf = [1209,1336,1447];
            
                 fs=8000; %frecuencia de muestreo (operación -->gráfica y sound)
                 tn=[0:1/fs:0.8]; %vector de tiempo para las señales numéricas
            
                %Definimos la señal de cada dígito
            
                % tecla dos
                s1 = 0.5*[sin(2*pi*filaf(2)*tn)+sin(2*pi*coluf(2)*tn)];
                % oscilograma
                %gráfica en el dominio del tiempo
                axes(handles.oscilograma);% etiqueta del primer eje
                cla;
                plot(tn,s1/max(s1));  % señal normalizada
                title('Oscilograma de la señal numérica al teclear "2"'),xlabel('t(s)'),ylabel('Suma de senos de 697 Hz y 1336 Hz');
                % espectograma
                    %transformada de fourier
                    ffts1 = fftshift(fft(s1));
                    %eje de frecuencias
                    frec = linspace(-fs/2,fs/2,length(ffts1));
                %gráfica en el dominio de la frecuencia
                axes(handles.espectograma); % etiqueta del segundo eje
                cla;
                plot(frec,abs((ffts1)/max(ffts1))); %espectro normalizado
                title('Espectro de la señal numérica al teclear "2"'),xlabel('f(Hz)'),ylabel('Espectro de la señal');
                sound(s1,fs);
        end

        % Menu selected function: Submenu6jrb
        function Submenu6jrb_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to Submenu6jrb (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            filaf = [697,770,852,941];
               coluf = [1209,1336,1447];
            
                 fs=8000; %frecuencia de muestreo (operación -->gráfica y sound)
                 tn=[0:1/fs:0.8]; %vector de tiempo para las señales numéricas
            
                %Definimos la señal de cada dígito
            
                % tecla dos
                s1 = 0.5*[sin(2*pi*filaf(2)*tn)+sin(2*pi*coluf(3)*tn)];
                % oscilograma
                %gráfica en el dominio del tiempo
                axes(handles.oscilograma);% etiqueta del primer eje
                cla;
                plot(tn,s1/max(s1));  % señal normalizada
                title('Oscilograma de la señal numérica al teclear "2"'),xlabel('t(s)'),ylabel('Suma de senos de 697 Hz y 1336 Hz');
                % espectograma
                    %transformada de fourier
                    ffts1 = fftshift(fft(s1));
                    %eje de frecuencias
                    frec = linspace(-fs/2,fs/2,length(ffts1));
                %gráfica en el dominio de la frecuencia
                axes(handles.espectograma); % etiqueta del segundo eje
                cla;
                plot(frec,abs((ffts1)/max(ffts1))); %espectro normalizado
                title('Espectro de la señal numérica al teclear "2"'),xlabel('f(Hz)'),ylabel('Espectro de la señal');
                sound(s1,fs);
        end

        % Menu selected function: Submenu7jrb
        function Submenu7jrb_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to Submenu7jrb (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            filaf = [697,770,852,941];
               coluf = [1209,1336,1447];
            
                 fs=8000; %frecuencia de muestreo (operación -->gráfica y sound)
                 tn=[0:1/fs:0.8]; %vector de tiempo para las señales numéricas
            
                %Definimos la señal de cada dígito
            
                % tecla dos
                s1 = 0.5*[sin(2*pi*filaf(3)*tn)+sin(2*pi*coluf(1)*tn)];
                % oscilograma
                %gráfica en el dominio del tiempo
                axes(handles.oscilograma);% etiqueta del primer eje
                cla;
                plot(tn,s1/max(s1));  % señal normalizada
                title('Oscilograma de la señal numérica al teclear "2"'),xlabel('t(s)'),ylabel('Suma de senos de 697 Hz y 1336 Hz');
                % espectograma
                    %transformada de fourier
                    ffts1 = fftshift(fft(s1));
                    %eje de frecuencias
                    frec = linspace(-fs/2,fs/2,length(ffts1));
                %gráfica en el dominio de la frecuencia
                axes(handles.espectograma); % etiqueta del segundo eje
                cla;
                plot(frec,abs((ffts1)/max(ffts1))); %espectro normalizado
                title('Espectro de la señal numérica al teclear "2"'),xlabel('f(Hz)'),ylabel('Espectro de la señal');
                sound(s1,fs);
        end

        % Menu selected function: Submenu8jrb
        function Submenu8jrb_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to Submenu8jrb (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            filaf = [697,770,852,941];
               coluf = [1209,1336,1447];
            
                 fs=8000; %frecuencia de muestreo (operación -->gráfica y sound)
                 tn=[0:1/fs:0.8]; %vector de tiempo para las señales numéricas
            
                %Definimos la señal de cada dígito
            
                % tecla dos
                s1 = 0.5*[sin(2*pi*filaf(3)*tn)+sin(2*pi*coluf(2)*tn)];
                % oscilograma
                %gráfica en el dominio del tiempo
                axes(handles.oscilograma);% etiqueta del primer eje
                cla;
                plot(tn,s1/max(s1));  % señal normalizada
                title('Oscilograma de la señal numérica al teclear "2"'),xlabel('t(s)'),ylabel('Suma de senos de 697 Hz y 1336 Hz');
                % espectograma
                    %transformada de fourier
                    ffts1 = fftshift(fft(s1));
                    %eje de frecuencias
                    frec = linspace(-fs/2,fs/2,length(ffts1));
                %gráfica en el dominio de la frecuencia
                axes(handles.espectograma); % etiqueta del segundo eje
                cla;
                plot(frec,abs((ffts1)/max(ffts1))); %espectro normalizado
                title('Espectro de la señal numérica al teclear "2"'),xlabel('f(Hz)'),ylabel('Espectro de la señal');
                sound(s1,fs);
        end

        % Menu selected function: Submenu9jrb
        function Submenu9jrb_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to Submenu9jrb (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            filaf = [697,770,852,941];
               coluf = [1209,1336,1447];
            
                 fs=8000; %frecuencia de muestreo (operación -->gráfica y sound)
                 tn=[0:1/fs:0.8]; %vector de tiempo para las señales numéricas
            
                %Definimos la señal de cada dígito
            
                % tecla dos
                s1 = 0.5*[sin(2*pi*filaf(3)*tn)+sin(2*pi*coluf(3)*tn)];
                % oscilograma
                %gráfica en el dominio del tiempo
                axes(handles.oscilograma);% etiqueta del primer eje
                cla;
                plot(tn,s1/max(s1));  % señal normalizada
                title('Oscilograma de la señal numérica al teclear "2"'),xlabel('t(s)'),ylabel('Suma de senos de 697 Hz y 1336 Hz');
                % espectograma
                    %transformada de fourier
                    ffts1 = fftshift(fft(s1));
                    %eje de frecuencias
                    frec = linspace(-fs/2,fs/2,length(ffts1));
                %gráfica en el dominio de la frecuencia
                axes(handles.espectograma); % etiqueta del segundo eje
                cla;
                plot(frec,abs((ffts1)/max(ffts1))); %espectro normalizado
                title('Espectro de la señal numérica al teclear "2"'),xlabel('f(Hz)'),ylabel('Espectro de la señal');
                sound(s1,fs);
        end

        % Button pushed function: tecla0
        function tecla0_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to tecla0 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            filaf = [697,770,852,941];
            coluf = [1209,1336,1447];
            
            fs=100000; %frecuencia de muestreo (operación -->gráfica y sound)
            tn=[0:1/fs:1]; %vector de tiempo para las señales numéricas
            
            %Definimos la señal de cada dígito
            s1 = 0.5*[sin(2*pi*filaf(4)*tn)+sin(2*pi*coluf(2)*tn)];
            
            % oscilograma
            %gráfica en el dominio del tiempo
            axes(handles.oscilograma);% etiqueta del primer eje
            cla;
            plot(tn,s1/max(s1));  % señal normalizada
            grid on
            axis([0,0.015,-1,1])
            title('Oscilograma de la señal numérica al teclear "0"'),xlabel('t(s)'),ylabel('Suma de senos de 941 Hz y 1336 Hz');
            
            % espectograma
                %transformada de fourier
                ffts1 = fftshift(fft(s1));
                %eje de frecuencias
                frec = linspace(-fs/2,fs/2,length(ffts1));
            %gráfica en el dominio de la frecuencia
            axes(handles.espectograma); % etiqueta del segundo eje
            cla;
            plot(frec,abs((ffts1)/max(ffts1)),'m'); %espectro normalizado
            axis([0,1600,0,0.03]);
            title('Espectro de la señal numérica al teclear "0"'),xlabel('f(Hz)'),ylabel('Espectro de la señal');
            sound(s1,fs);
        end

        % Button pushed function: tecla1
        function tecla1_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to tecla1 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            filaf = [697,770,852,941];
            coluf = [1209,1336,1447];
            
            fs=100000; %frecuencia de muestreo (operación -->gráfica y sound)
            tn=[0:1/fs:1]; %vector de tiempo para las señales numéricas
            
            %Se define la señal de cada dígito
            % tecla uno
            s1 = 0.5*[sin(2*pi*filaf(1)*tn)+sin(2*pi*coluf(1)*tn)];
            % oscilograma
            %gráfica en el dominio del tiempo
            
            axes(handles.oscilograma); % etiqueta del primer eje
            cla;
            plot(tn,s1/max(s1));  % señal normalizada
            grid on
            axis([0,0.023,-1,1]);
            title('Oscilograma de la señal numérica al teclear "1"'),xlabel('t(s)'),ylabel('Suma de senos de 697 Hz y 1209 Hz');
            
            % espectograma
                %transformada de fourier
                ffts1 = fftshift(fft(s1));
                %eje de frecuencias
                frec = linspace(-fs/2,fs/2,length(ffts1));
            
                %gráfica en el dominio de la frecuencia
            axes(handles.espectograma); % etiqueta del segundo eje
            cla;
            plot(frec,abs((ffts1)/max(ffts1)),'m'); %espectro normalizado
            axis([0,1600,0,0.03]);
            title('Espectro de la señal numérica al teclear "1"'),xlabel('f(Hz)'),ylabel('Espectro de la señal');
            sound(s1,fs);
        end

        % Button pushed function: tecla2
        function tecla2_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to tecla2 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            filaf = [697,770,852,941];
            coluf = [1209,1336,1447];
        
            fs=100000; %frecuencia de muestreo (operación -->gráfica y sound)
            tn=[0:1/fs:1]; %vector de tiempo para las señales numéricas
        
            %Definimos la señal de cada dígito
            % tecla dos
            s1 = 0.5*[sin(2*pi*filaf(1)*tn)+sin(2*pi*coluf(2)*tn)];
            
            % oscilograma
            %gráfica en el dominio del tiempo
            axes(handles.oscilograma);% etiqueta del primer eje
            cla;
            plot(tn,s1/max(s1));  % señal normalizada
            grid on
            axis([0,0.026,-1,1]);
            title('Oscilograma de la señal numérica al teclear "2"'),xlabel('t(s)'),ylabel('Suma de senos de 697 Hz y 1336 Hz');
            
            % espectograma
                %transformada de fourier
                ffts1 = fftshift(fft(s1));
                %eje de frecuencias
                frec = linspace(-fs/2,fs/2,length(ffts1));
            %gráfica en el dominio de la frecuencia
            axes(handles.espectograma); % etiqueta del segundo eje
            cla;
            plot(frec,abs((ffts1)/max(ffts1)),'m'); %espectro normalizado
            axis([0,1600,0,0.03]);
            title('Espectro de la señal numérica al teclear "2"'),xlabel('f(Hz)'),ylabel('Espectro de la señal');
            sound(s1,fs);
        end

        % Button pushed function: tecla3
        function tecla3_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to tecla3 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            filaf = [697,770,852,941];
            coluf = [1209,1336,1447];
            
            fs=100000; %frecuencia de muestreo (operación -->gráfica y sound)
            tn=[0:1/fs:1]; %vector de tiempo para las señales numéricas
        
            %Definimos la señal de cada dígito
            s1 = 0.5*[sin(2*pi*filaf(1)*tn)+sin(2*pi*coluf(3)*tn)];
            
            % oscilograma
            %gráfica en el dominio del tiempo
            axes(handles.oscilograma);% etiqueta del primer eje
            cla;
            plot(tn,s1/max(s1));  % señal normalizada
            grid on
            axis([0,0.031,-1,1]);
            title('Oscilograma de la señal numérica al teclear "3"'),xlabel('t(s)'),ylabel('Suma de senos de 697 Hz y 1447 Hz');
            
            % espectograma
                %transformada de fourier
                ffts1 = fftshift(fft(s1));
                %eje de frecuencias
                frec = linspace(-fs/2,fs/2,length(ffts1));
            %gráfica en el dominio de la frecuencia
            axes(handles.espectograma); % etiqueta del segundo eje
            cla;
            plot(frec,abs((ffts1)/max(ffts1)),'m'); %espectro normalizado
            axis([0,1600,0,0.5]);
            title('Espectro de la señal numérica al teclear "3"'),xlabel('f(Hz)'),ylabel('Espectro de la señal');
            sound(s1,fs);
        end

        % Button pushed function: tecla4
        function tecla4_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to tecla4 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            filaf = [697,770,852,941];
            coluf = [1209,1336,1447];
            
            fs=100000; %frecuencia de muestreo (operación -->gráfica y sound)
            tn=[0:1/fs:1]; %vector de tiempo para las señales numéricas
            
            %Definimos la señal de cada dígito
            s1 = 0.5*[sin(2*pi*filaf(2)*tn)+sin(2*pi*coluf(1)*tn)];
            
            % oscilograma
            %gráfica en el dominio del tiempo
            axes(handles.oscilograma);% etiqueta del primer eje
            cla;
            plot(tn,s1/max(s1));  % señal normalizada
            grid on
            axis([0,0.013,-1,1]);
            title('Oscilograma de la señal numérica al teclear "4"'),xlabel('t(s)'),ylabel('Suma de senos de 770 Hz y 1209 Hz');
            
            % espectograma
                %transformada de fourier
                ffts1 = fftshift(fft(s1));
                %eje de frecuencias
                frec = linspace(-fs/2,fs/2,length(ffts1));
            %gráfica en el dominio de la frecuencia
            axes(handles.espectograma); % etiqueta del segundo eje
            cla;
            plot(frec,abs((ffts1)/max(ffts1)),'m'); %espectro normalizado
            axis([0,1600,0,0.5]);
            title('Espectro de la señal numérica al teclear "4"'),xlabel('f(Hz)'),ylabel('Espectro de la señal');
            sound(s1,fs);
        end

        % Button pushed function: tecla5
        function tecla5_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to tecla5 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            filaf = [697,770,852,941];
            coluf = [1209,1336,1447];
            
            fs=100000; %frecuencia de muestreo (operación -->gráfica y sound)
            tn=[0:1/fs:1]; %vector de tiempo para las señales numéricas
            
            %Definimos la señal de cada dígito
            s1 = 0.5*[sin(2*pi*filaf(2)*tn)+sin(2*pi*coluf(2)*tn)];
            
            % oscilograma
            %gráfica en el dominio del tiempo
            axes(handles.oscilograma);% etiqueta del primer eje
            cla;
            plot(tn,s1/max(s1));  % señal normalizada
            grid on
            axis([0,0.030,-1,1]);
            title('Oscilograma de la señal numérica al teclear "5"'),xlabel('t(s)'),ylabel('Suma de senos de 770 Hz y 1336 Hz');
            
            % espectograma
                %transformada de fourier
                ffts1 = fftshift(fft(s1));
                %eje de frecuencias
                frec = linspace(-fs/2,fs/2,length(ffts1));
            %gráfica en el dominio de la frecuencia
            axes(handles.espectograma); % etiqueta del segundo eje
            cla;
            plot(frec,abs((ffts1)/max(ffts1)),'m'); %espectro normalizado
            axis([0,1600,0,0.5]);
            title('Espectro de la señal numérica al teclear "5"'),xlabel('f(Hz)'),ylabel('Espectro de la señal');
            sound(s1,fs);
        end

        % Button pushed function: tecla6
        function tecla6_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to tecla6 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            filaf = [697,770,852,941];
            coluf = [1209,1336,1447];
            
            fs=100000; %frecuencia de muestreo (operación -->gráfica y sound)
            tn=[0:1/fs:1]; %vector de tiempo para las señales numéricas
            
            %Definimos la señal de cada dígito
  
            s1 = 0.5*[sin(2*pi*filaf(2)*tn)+sin(2*pi*coluf(3)*tn)];
            
            % oscilograma
            %gráfica en el dominio del tiempo
            axes(handles.oscilograma);% etiqueta del primer eje
            cla;
            plot(tn,s1/max(s1));  % señal normalizada
            grid on
            axis([0,0.026,-1,1]);
            title('Oscilograma de la señal numérica al teclear "6"'),xlabel('t(s)'),ylabel('Suma de senos de 770 Hz y 1447 Hz');
            
            % espectograma
                %transformada de fourier
                ffts1 = fftshift(fft(s1));
                %eje de frecuencias
                frec = linspace(-fs/2,fs/2,length(ffts1));
            %gráfica en el dominio de la frecuencia
            axes(handles.espectograma); % etiqueta del segundo eje
            cla;
            plot(frec,abs((ffts1)/max(ffts1)),'m'); %espectro normalizado
            axis([0,1600,0,0.5]);
            title('Espectro de la señal numérica al teclear "6"'),xlabel('f(Hz)'),ylabel('Espectro de la señal');
            sound(s1,fs);
        end

        % Button pushed function: tecla7
        function tecla7_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to tecla7 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            filaf = [697,770,852,941];
            coluf = [1209,1336,1447];
            
            fs=100000; %frecuencia de muestreo (operación -->gráfica y sound)
            tn=[0:1/fs:1]; %vector de tiempo para las señales numéricas
            
            %Definimos la señal de cada dígito
            s1 = 0.5*[sin(2*pi*filaf(3)*tn)+sin(2*pi*coluf(1)*tn)];
            
            % oscilograma
            %gráfica en el dominio del tiempo
            axes(handles.oscilograma);% etiqueta del primer eje
            cla;
            plot(tn,s1/max(s1));  % señal normalizada
            grid on
            axis([0,0.017,-1,1]);
            title('Oscilograma de la señal numérica al teclear "7"'),xlabel('t(s)'),ylabel('Suma de senos de 852 Hz y 1209 Hz');
            
            % espectograma
                %transformada de fourier
                ffts1 = fftshift(fft(s1));
                %eje de frecuencias
                frec = linspace(-fs/2,fs/2,length(ffts1));
            %gráfica en el dominio de la frecuencia
            axes(handles.espectograma); % etiqueta del segundo eje
            cla;
            plot(frec,abs((ffts1)/max(ffts1)),'m'); %espectro normalizado
            axis([0,1600,0,0.5]);
            title('Espectro de la señal numérica al teclear "7"'),xlabel('f(Hz)'),ylabel('Espectro de la señal');
            sound(s1,fs);
        end

        % Button pushed function: tecla8
        function tecla8_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to tecla8 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            filaf = [697,770,852,941];
            coluf = [1209,1336,1447];
            
            fs=100000; %frecuencia de muestreo (operación -->gráfica y sound)
            tn=[0:1/fs:1]; %vector de tiempo para las señales numéricas
            
            %Definimos la señal de cada dígito
            s1 = 0.5*[sin(2*pi*filaf(3)*tn)+sin(2*pi*coluf(2)*tn)];
            
            % oscilograma
            %gráfica en el dominio del tiempo
            axes(handles.oscilograma);% etiqueta del primer eje
            cla;
            plot(tn,s1/max(s1));  % señal normalizada
            grid on
            axis([0,0.011,-1,1]);
            title('Oscilograma de la señal numérica al teclear "8"'),xlabel('t(s)'),ylabel('Suma de senos de 852 Hz y 1336 Hz');
            
            % espectograma
                %transformada de fourier
                ffts1 = fftshift(fft(s1));
                %eje de frecuencias
                frec = linspace(-fs/2,fs/2,length(ffts1));
            %gráfica en el dominio de la frecuencia
            axes(handles.espectograma); % etiqueta del segundo eje
            cla;
            plot(frec,abs((ffts1)/max(ffts1)),'m'); %espectro normalizado
            axis([0,1600,0,0.5]);
            title('Espectro de la señal numérica al teclear "8"'),xlabel('f(Hz)'),ylabel('Espectro de la señal');
            sound(s1,fs);
        end

        % Button pushed function: tecla9
        function tecla9_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to tecla9 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            filaf = [697,770,852,941];
            coluf = [1209,1336,1447];
            
            fs=100000; %frecuencia de muestreo (operación -->gráfica y sound)
            tn=[0:1/fs:1]; %vector de tiempo para las señales numéricas
            
            %Definimos la señal de cada dígito
            s1 = 0.5*[sin(2*pi*filaf(3)*tn)+sin(2*pi*coluf(3)*tn)];
            
            % oscilograma
            %gráfica en el dominio del tiempo
            axes(handles.oscilograma);% etiqueta del primer eje
            cla;
            plot(tn,s1/max(s1));  % señal normalizada
            grid on
            axis([0,0.014,-1,1]);
            title('Oscilograma de la señal numérica al teclear "9"'),xlabel('t(s)'),ylabel('Suma de senos de 852 Hz y 1447 Hz');
            
            % espectograma
                %transformada de fourier
                ffts1 = fftshift(fft(s1));
                %eje de frecuencias
                frec = linspace(-fs/2,fs/2,length(ffts1));
            %gráfica en el dominio de la frecuencia
            axes(handles.espectograma); % etiqueta del segundo eje
            cla;
            plot(frec,abs((ffts1)/max(ffts1)),'m'); %espectro normalizado
            axis([0,1600,0,0.5]);
            title('Espectro de la señal numérica al teclear "9"'),xlabel('f(Hz)'),ylabel('Espectro de la señal');
            sound(s1,fs);
        end

        % Button pushed function: teclaAst
        function teclaAst_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to teclaAst (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            filaf = [697,770,852,941];
            coluf = [1209,1336,1447];
               
            fs=100000; %frecuencia de muestreo (operación -->gráfica y sound)
            tn=[0:1/fs:1]; %vector de tiempo para las señales numéricas
            
            %Definimos la señal de cada dígito

            s1 = 0.5*[sin(2*pi*filaf(4)*tn)+sin(2*pi*coluf(1)*tn)];
            
            % oscilograma
            %gráfica en el dominio del tiempo
            axes(handles.oscilograma);% etiqueta del primer eje
            cla;
            plot(tn,s1/max(s1));  % señal normalizada
            grid on
            axis([0,0.011,-1,1]);
            title('Oscilograma de la señal numérica al teclear "*"'),xlabel('t(s)'),ylabel('Suma de senos de 941 Hz y 1209 Hz');
            
            % espectograma
                %transformada de fourier
                ffts1 = fftshift(fft(s1));
                %eje de frecuencias
                frec = linspace(-fs/2,fs/2,length(ffts1));
            %gráfica en el dominio de la frecuencia
            axes(handles.espectograma); % etiqueta del segundo eje
            cla;
            plot(frec,abs((ffts1)/max(ffts1)),'m'); %espectro normalizado
            axis([0,1600,0,0.5]);
            title('Espectro de la señal numérica al teclear "*"'),xlabel('f(Hz)'),ylabel('Espectro de la señal');
            sound(s1,fs);
        end

        % Button pushed function: teclaGato
        function teclaGato_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to teclaGato (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            filaf = [697,770,852,941];
            coluf = [1209,1336,1447];
            
            fs=100000; %frecuencia de muestreo (operación -->gráfica y sound)
            tn=[0:1/fs:1]; %vector de tiempo para las señales numéricas
            
            %Definimos la señal de cada dígito
            s1 = 0.5*[sin(2*pi*filaf(4)*tn)+sin(2*pi*coluf(3)*tn)];
            
            % oscilograma
            %gráfica en el dominio del tiempo
            axes(handles.oscilograma);% etiqueta del primer eje
            cla;
            plot(tn,s1/max(s1));  % señal normalizada
            axis([0,0.02,-1,1]);
            grid on
            title('Oscilograma de la señal numérica al teclear "#"'),xlabel('t(s)'),ylabel('Suma de senos de 941 Hz y 1447 Hz');
            
            % espectograma
                %transformada de fourier
                ffts1 = fftshift(fft(s1));
                %eje de frecuencias
                frec = linspace(-fs/2,fs/2,length(ffts1));
            %gráfica en el dominio de la frecuencia
            axes(handles.espectograma); % etiqueta del segundo eje
            cla;
            plot(frec,abs((ffts1)/max(ffts1)),'m'); %espectro normalizado
            axis([0,1600,0,0.5]);
            title('Espectro de la señal numérica al teclear "#"'),xlabel('f(Hz)'),ylabel('Espectro de la señal');
            sound(s1,fs);
        end

        % Button pushed function: llamada
        function timbrado_callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to teclaGato (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
                FS=20000; %frecuencia de muestreo (operación -->gráfica y sound)
                TN=[0:1/FS:2]; %vector de tiempo para las señales numéricas
            
                %Definimos la señal de cada dígito
                S1 = 0.5*[sin(2*pi*25*TN)];

                fs=100000; %frecuencia de muestreo (operación -->gráfica y sound)
                tn=[0:1/fs:1]; %vector de tiempo para las señales numéricas
            
                %Definimos la señal de cada dígito
                s1 = 0.5*[sin(2*pi*25*tn)];
                
                % oscilograma
                %gráfica en el dominio del tiempo
                axes(handles.oscilograma);% etiqueta del primer eje
                cla;
                plot(tn,s1/max(s1));  % señal normalizada
                axis([0,0.1,-1,1]);
                grid on
                title('Oscilograma de la señal telefonica de Llamada'),xlabel('t(s)'),ylabel('Seno de 425 Hz');
                
                % espectograma
                    %transformada de fourier
                    ffts1 = fftshift(fft(s1));
                    %eje de frecuencias
                    frec = linspace(-fs/2,fs/2,length(ffts1));
                %gráfica en el dominio de la frecuencia
                axes(handles.espectograma); % etiqueta del segundo eje
                cla;
                plot(frec,abs((ffts1)/max(ffts1)),'m'); %espectro normalizado
                axis([0,50,0,1]);
                title('Espectro de la señal telefonica de Llamada'),xlabel('f(Hz)'),ylabel('Espectro de la señal');
                for n = 0:1:2
                    sound(S1,FS);
                    pause(4);
                end
        end

        % Button pushed function: llamada_2
        function invitacion_callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to teclaGato (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            frecuencias = [425,350,380];
        
            fs=50000; %frecuencia de muestreo (operación -->gráfica y sound)
            tn=[0:1/fs:5]; %vector de tiempo para las señales numéricas
        
            %Definimos la señal de cada dígito
        
            % tecla dos
            s1 = 0.5*[sin(2*pi*425*tn)];
            %s1 = 0.5*[sin(2*pi*frecuencias(1)*tn) + sin(2*pi*frecuencias(2)*tn) + sin(2*pi*frecuencias(3)*tn)];
           
            % oscilograma
            %gráfica en el dominio del tiempo
            axes(handles.oscilograma);% etiqueta del primer eje
            cla;
            plot(tn,s1/max(s1));  % señal normalizada
            grid on
            axis([0,0.01,-1,1]);
            title('Oscilograma de la señal telefonica de Invitacion A Marcar'),xlabel('t(s)'),ylabel('Seno de 425 Hz');
            
            % espectograma
                %transformada de fourier
                ffts1 = fftshift(fft(s1));
                %eje de frecuencias
                frec = linspace(-fs/2,fs/2,length(ffts1));
            %gráfica en el dominio de la frecuencia
            axes(handles.espectograma); % etiqueta del segundo eje
            cla;
            plot(frec,abs((ffts1)/max(ffts1)),'m'); %espectro normalizado
            axis([0,500,0,0.03]);
            title('Espectro de la señal telefonica de Invitacion A Marcar'),xlabel('f(Hz)'),ylabel('Espectro de la señal');
            sound(s1,fs);
        end

        % Button pushed function: llamada_4
        function llamando_callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to teclaGato (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
                fs=10000; %frecuencia de muestreo (operación -->gráfica y sound)
                tn=[0:1/fs:1]; %vector de tiempo para las señales numéricas
            
                %Definimos la señal de cada dígito
            
                % tecla dos
                s1 = 0.5*[sin(2*pi*425*tn)];
                
                % oscilograma
                %gráfica en el dominio del tiempo
                axes(handles.oscilograma);% etiqueta del primer eje
                cla;
                plot(tn,s1/max(s1));  % señal normalizada
                grid on
                axis([0,0.02,-1,1]);
                title('Oscilograma de la señal telefonica de Llamada'),xlabel('t(s)'),ylabel('Seno de 425 Hz');
                
                % espectograma
                    %transformada de fourier
                    ffts1 = fftshift(fft(s1));
                    %eje de frecuencias
                    frec = linspace(-fs/2,fs/2,length(ffts1));
                %gráfica en el dominio de la frecuencia
                axes(handles.espectograma); % etiqueta del segundo eje
                cla;
                plot(frec,abs((ffts1)/max(ffts1)),'m'); %espectro normalizado
                axis([0,500,0,0.1]);
                grid on
                title('Espectro de la señal telefonica de Llamada'),xlabel('f(Hz)'),ylabel('Espectro de la señal');
                for n = 0:1:3
                    sound(s1,fs);
                    pause(4);
                end
        end

        % Button pushed function: llamada_5
        function espera_callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to teclaGato (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
                %fs=4000; %frecuencia de muestreo (operación -->gráfica y sound)
                %tn=[0:1/fs:0.400]; %vector de tiempo para las señales numéricas
                FS = 1000;
                TN=[0:1/FS:0.1];

                %Definimos la señal de cada dígito
                S1 = 0.5*[sin(2*pi*425*TN)];
                
                %%%%Señal para graficar
                %fs=4000; %frecuencia de muestreo (operación -->gráfica y sound)
                %tn=[0:1/fs:0.400]; %vector de tiempo para las señales numéricas
                fs = 100000;
                tn=[0:1/fs:1];
                %Definimos la señal de cada dígito
                s1 = 0.5*[sin(2*pi*425*tn)];

                % oscilograma
                %gráfica en el dominio del tiempo
                axes(handles.oscilograma);% etiqueta del primer eje
                cla;
                plot(tn,s1/max(s1));  % señal normalizada
                grid on
                axis([0,0.02,-1,1]);
                title('Oscilograma de la señal telefonica de Llamada En Espera'),xlabel('t(s)'),ylabel('Seno de 425 Hz');
                
                % espectograma
                    %transformada de fourier
                    ffts1 = fftshift(fft(s1));
                    %eje de frecuencias
                    frec = linspace(-fs/2,fs/2,length(ffts1));
                %gráfica en el dominio de la frecuencia
                axes(handles.espectograma); % etiqueta del segundo eje
                cla;
                plot(frec,abs((ffts1)/max(ffts1)),'m'); %espectro normalizado
                axis([0,500,0,0.1]);
                title('Espectro de la señal telefonica de Llamada En Espera'),xlabel('f(Hz)'),ylabel('Espectro de la señal');
                
                for n = 0:1:1
                    sound(S1,FS);
                    pause(200/1000);
                    sound(S1,FS);
                    pause(8);
                end
        end

        % Button pushed function: llamada_6
        function ocupado_callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to teclaGato (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
                %Para graficar
                FS=100000; %frecuencia de muestreo (operación -->gráfica y sound)
                TN=[0:1/FS:1]; %vector de tiempo para las señales numéricas
                   
                %Para audio
                fs=6000; %frecuencia de muestreo (operación -->gráfica y sound)
                tn=[0:1/fs:0.6]; %vector de tiempo para las señales numéricas
                
                %Definimos la señal de cada dígito
                s1 = 0.5*[sin(2*pi*480*TN) + sin(2*pi*620*TN)];
                S1 = 0.5*[sin(2*pi*480*tn) + sin(2*pi*620*tn)];
                % oscilograma
                %gráfica en el dominio del tiempo
                axes(handles.oscilograma);% etiqueta del primer eje
                cla;
                plot(TN,s1/max(s1));  % señal normalizada
                grid on
                axis([0,0.057,-1,1]);
                title('Oscilograma de la señal de linea ocupada'),xlabel('t(s)'),ylabel('Suma de senos de 480 Hz y 620 Hz');
                
                % espectograma
                    %transformada de fourier
                    ffts1 = fftshift(fft(s1));
                    %eje de frecuencias
                    frec = linspace(-FS/2,FS/2,length(ffts1));
                %gráfica en el dominio de la frecuencia
                axes(handles.espectograma); % etiqueta del segundo eje
                cla;
                plot(frec,abs((ffts1)/max(ffts1)),'m'); %espectro normalizado
                axis([0,700,0,0.1]);
                title('Espectro de la señal de linea ocupada'),xlabel('f(Hz)'),ylabel('Espectro de la señal');
                
                for n = 0:1:6
                    sound(S1,fs);
                    pause(1);
                end
        end

        % Button pushed function: llamada_3
        function bloqueo_callback(app, event)
             % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to teclaGato (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
                %Para Graficar
                fs=100000; %frecuencia de muestreo (operación -->gráfica y sound)
                tn=[0:1/fs:1]; %vector de tiempo para las señales numéricas
                s1 = 0.5*[sin(2*pi*480*tn) + sin(2*pi*620*tn)];

                FS=2500; %frecuencia de muestreo (operación -->gráfica y sound)
                TN=[0:1/FS:0.25]; %vector de tiempo para las señales numéricas
            
                %Definimos la señal de cada dígito
                %s1 = 0.5*[sin(2*pi*420*tn)];
                S1 = 0.5*[sin(2*pi*480*TN) + sin(2*pi*620*TN)];
                
                % oscilograma
                %gráfica en el dominio del tiempo
                axes(handles.oscilograma);% etiqueta del primer eje
                cla;
                plot(tn,s1/max(s1));  % señal normalizada
                grid on
                axis([0,0.057,-1,1]);
                title('Oscilograma de la señal de linea Bloqueda'),xlabel('t(s)'),ylabel('Suma de senos de 480 Hz y 620 Hz');
                
                % espectograma
                    %transformada de fourier
                    ffts1 = fftshift(fft(s1));
                    %eje de frecuencias
                    frec = linspace(-fs/2,fs/2,length(ffts1));
                %gráfica en el dominio de la frecuencia
                axes(handles.espectograma); % etiqueta del segundo eje
                cla;
                plot(frec,abs((ffts1)/max(ffts1)),'m'); %espectro normalizado
                axis([0,700,0,0.1]);
                title('Espectro de la señal de linea Bloqueda'),xlabel('f(Hz)'),ylabel('Espectro de la señal');
                for n = 0:1:10
                    sound(S1,FS);
                    pause(1);
                end
        end

        % Button pushed function: llamada_7
        function operadora_callback(app, event)
             %Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to teclaGato (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
                %Para Audio
                FS=3300; %frecuencia de muestreo (operación -->gráfica y sound)
                TN=[0:1/FS:0.33]; %vector de tiempo para las señales numéricas
            
                %Definimos la señal de cada dígito
                S1 = 0.5*[sin(2*pi*950*TN)];
                S2 = 0.5*[sin(2*pi*1400*TN)];
                S3 = 0.5*[sin(2*pi*1800*TN)];
                

                fs=100000; %frecuencia de muestreo (operación -->gráfica y sound)
                tn=[0:1/fs:1]; %vector de tiempo para las señales numéricas
            
                %Definimos la señal de cada dígito
                s1 = 0.5*[sin(2*pi*950*tn)];
                s2 = 0.5*[sin(2*pi*1400*tn)];
                s3 = 0.5*[sin(2*pi*1800*tn)];
                
                s4 = 0.5*[sin(2*pi*950*tn) + sin(2*pi*1400*tn) + sin(2*pi*1800*tn)];
                % oscilograma
                %gráfica en el dominio del tiempo
                axes(handles.oscilograma);% etiqueta del primer eje
                cla;
                p(1) = plot(tn,s1/max(s1));  % señal normalizada
                hold on
                p(2) = plot(tn,s2/max(s2));
                p(3) = plot(tn,s3/max(s3));
                axis([0,0.0014,-1,1]);
                legend(p,'950Hz','1400Hz','1800Hz')
                grid on
                title('Oscilograma de la señal telefónica de intervención por operadora'),xlabel('t(s)'),ylabel('Suma de senos de 697 Hz y 1336 Hz');
                % espectograma
                    %transformada de fourier
                    ffts1 = fftshift(fft(s4));
                    %eje de frecuencias
                    frec = linspace(-fs/2,fs/2,length(ffts1));
                %gráfica en el dominio de la frecuencia
                axes(handles.espectograma); % etiqueta del segundo eje
                cla;
                plot(frec,abs((ffts1)/max(ffts1)),'m'); %espectro normalizado
                axis([0,1900,0,0.8]);
                title('Espectro de la señal telefónica de intervención por operadora'),xlabel('f(Hz)'),ylabel('Espectro de la señal');
                for n = 0:1:2
                    sound(S1,FS);
                    pause(0.3)
                    sound(S2,FS);
                    pause(0.3)
                    sound(S3,FS);
                    pause(1)
                end
        end

        % Button pushed function: llamada_8
        function invita(app, event)
             % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to teclaGato (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            frecuencias = [425,350,380];
        
            fs=50000; %frecuencia de muestreo (operación -->gráfica y sound)
            tn=[0:1/fs:5]; %vector de tiempo para las señales numéricas
        
            %Definimos la señal de cada dígito
        
            % tecla dos
            %s1 = 0.5*[sin(2*pi*425*tn)];
            s1 = 0.5*[sin(2*pi*frecuencias(1)*tn) + sin(2*pi*frecuencias(2)*tn) + sin(2*pi*frecuencias(3)*tn)];
           
            % oscilograma
            %gráfica en el dominio del tiempo
            axes(handles.oscilograma);% etiqueta del primer eje
            cla;
            plot(tn,s1/max(s1));  % señal normalizada
            grid on
            axis([0,0.07,-1,1]);
            title('Oscilograma de la señal telefonica de Invitacion A Marcar'),xlabel('t(s)'),ylabel('Suma de senos de 425 Hz, 350 Hz, 380 Hz');
            
            % espectograma
                %transformada de fourier
                ffts1 = fftshift(fft(s1));
                %eje de frecuencias
                frec = linspace(-fs/2,fs/2,length(ffts1));
            %gráfica en el dominio de la frecuencia
            axes(handles.espectograma); % etiqueta del segundo eje
            cla;
            plot(frec,abs((ffts1)/max(ffts1)),'m'); %espectro normalizado
            axis([0,500,0,0.03]);
            title('Espectro de la señal telefonica de Invitacion A Marcar'),xlabel('f(Hz)'),ylabel('Espectro de la señal');
            sound(s1,fs);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'pra1';
            app.figure1.Tag = 'figure1';
            app.Menujrb = uimenu(app.figure1);
            app.Menujrb.Text = 'Teclado';
            app.Menujrb.Tag = 'Menujrb';
            app.Submenu1jrb = uimenu(app.Menujrb);
            app.Submenu1jrb.MenuSelectedFcn = createCallbackFcn(app, @Submenu1jrb_Callback, true);
            app.Submenu1jrb.Text = 'Digito #1';
            app.Submenu1jrb.Tag = 'Submenu1jrb';
            app.Submenu2jrb = uimenu(app.Menujrb);
            app.Submenu2jrb.MenuSelectedFcn = createCallbackFcn(app, @Submenu2jrb_Callback, true);
            app.Submenu2jrb.Text = 'Digito #2';
            app.Submenu2jrb.Tag = 'Submenu2jrb';
            app.Submenu3jrb = uimenu(app.Menujrb);
            app.Submenu3jrb.MenuSelectedFcn = createCallbackFcn(app, @Submenu3jrb_Callback, true);
            app.Submenu3jrb.Text = 'Digito #3';
            app.Submenu3jrb.Tag = 'Submenu3jrb';
            app.Submenu4jrb = uimenu(app.Menujrb);
            app.Submenu4jrb.MenuSelectedFcn = createCallbackFcn(app, @Submenu4jrb_Callback, true);
            app.Submenu4jrb.Text = 'Digito #4';
            app.Submenu4jrb.Tag = 'Submenu4jrb';
            app.Submenu5jrb = uimenu(app.Menujrb);
            app.Submenu5jrb.MenuSelectedFcn = createCallbackFcn(app, @Submenu5jrb_Callback, true);
            app.Submenu5jrb.Text = 'Digito #5';
            app.Submenu5jrb.Tag = 'Submenu5jrb';
            app.Submenu6jrb = uimenu(app.Menujrb);
            app.Submenu6jrb.MenuSelectedFcn = createCallbackFcn(app, @Submenu6jrb_Callback, true);
            app.Submenu6jrb.Text = 'Digito #6';
            app.Submenu6jrb.Tag = 'Submenu6jrb';
            app.Submenu7jrb = uimenu(app.Menujrb);
            app.Submenu7jrb.MenuSelectedFcn = createCallbackFcn(app, @Submenu7jrb_Callback, true);
            app.Submenu7jrb.Text = 'Digito #7';
            app.Submenu7jrb.Tag = 'Submenu7jrb';
            app.Submenu8jrb = uimenu(app.Menujrb);
            app.Submenu8jrb.MenuSelectedFcn = createCallbackFcn(app, @Submenu8jrb_Callback, true);
            app.Submenu8jrb.Text = 'Digito #8';
            app.Submenu8jrb.Tag = 'Submenu8jrb';
            app.Submenu9jrb = uimenu(app.Menujrb);
            app.Submenu9jrb.MenuSelectedFcn = createCallbackFcn(app, @Submenu9jrb_Callback, true);
            app.Submenu9jrb.Text = 'Digito #9';
            app.Submenu9jrb.Tag = 'Submenu9jrb';
            app.Submenu10jrb = uimenu(app.Menujrb);
            app.Submenu10jrb.MenuSelectedFcn = createCallbackFcn(app, @Submenu10jrb_Callback, true);
            app.Submenu10jrb.Text = 'Digito #10';
            app.Submenu10jrb.Tag = 'Submenu10jrb';
            app.Submenu11jrb = uimenu(app.Menujrb);
            app.Submenu11jrb.MenuSelectedFcn = createCallbackFcn(app, @Submenu11jrb_Callback, true);
            app.Submenu11jrb.Text = 'Digito #11';
            app.Submenu11jrb.Tag = 'Submenu11jrb';
            app.Submenu12jrb = uimenu(app.Menujrb);
            app.Submenu12jrb.MenuSelectedFcn = createCallbackFcn(app, @Submenu12jrb_Callback, true);
            app.Submenu12jrb.Text = 'Digito #12';
            app.Submenu12jrb.Tag = 'Submenu12jrb';
            app.Image = uiimage(app.figure1);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'fondo.jpg');
            app.TecladoPanel = uipanel(app.figure1);
            app.TecladoPanel.Title = 'Teclado';
            app.llamada_7 = uibutton(app.TecladoPanel, 'push');
            app.llamada_7.ButtonPushedFcn = createCallbackFcn(app, @operadora_callback, true);
            app.llamada_7.Tag = 'llamada';
            app.llamada_7.Icon = fullfile(pathToMLAPP, 'operador.png');
            app.llamada_7.Text = 'Operadora';
            app.llamada_6 = uibutton(app.TecladoPanel, 'push');
            app.llamada_6.ButtonPushedFcn = createCallbackFcn(app, @ocupado_callback, true);
            app.llamada_6.Tag = 'llamada';
            app.llamada_6.Icon = fullfile(pathToMLAPP, 'telefono-cuelga.png');
            app.llamada_6.Text = 'Ocupado';
            app.llamada_5 = uibutton(app.TecladoPanel, 'push');
            app.llamada_5.ButtonPushedFcn = createCallbackFcn(app, @espera_callback, true);
            app.llamada_5.Tag = 'llamada';
            app.llamada_5.Icon = fullfile(pathToMLAPP, 'espera.png');
            app.llamada_5.Text = 'Espera';
            app.llamada_4 = uibutton(app.TecladoPanel, 'push');
            app.llamada_4.ButtonPushedFcn = createCallbackFcn(app, @llamando_callback, true);
            app.llamada_4.Tag = 'llamada';
            app.llamada_4.Icon = fullfile(pathToMLAPP, 'llamada-telefonica.png');
            app.llamada_4.Text = 'Llamando';
            app.llamada_3 = uibutton(app.TecladoPanel, 'push');
            app.llamada_3.ButtonPushedFcn = createCallbackFcn(app, @bloqueo_callback, true);
            app.llamada_3.Tag = 'llamada';
            app.llamada_3.Icon = fullfile(pathToMLAPP, 'bloqueado.png');
            app.llamada_3.Text = 'Bloqueo';
            app.llamada_2 = uibutton(app.TecladoPanel, 'push');
            app.llamada_2.ButtonPushedFcn = createCallbackFcn(app, @invitacion_callback, true);
            app.llamada_2.Tag = 'llamada';
            app.llamada_2.Icon = fullfile(pathToMLAPP, 'comprobado.png');
            app.llamada_2.Text = 'Invitacion A Marcar';
            app.llamada = uibutton(app.TecladoPanel, 'push');
            app.llamada.ButtonPushedFcn = createCallbackFcn(app, @timbrado_callback, true);
            app.llamada.Tag = 'llamada';
            app.llamada.Icon = fullfile(pathToMLAPP, 'timbrado.png');
            app.llamada.Text = 'Timbrado';
            app.tecla1 = uibutton(app.TecladoPanel, 'push');
            app.tecla1.ButtonPushedFcn = createCallbackFcn(app, @tecla1_Callback, true);
            app.tecla1.Tag = 'tecla1';
            app.tecla1.Icon = fullfile(pathToMLAPP, 'redondo.png');
            app.tecla1.Text = '';
            app.tecla3 = uibutton(app.TecladoPanel, 'push');
            app.tecla3.ButtonPushedFcn = createCallbackFcn(app, @tecla3_Callback, true);
            app.tecla3.Tag = 'tecla3';
            app.tecla3.Icon = fullfile(pathToMLAPP, 'numero-3.png');
            app.tecla3.Text = '';
            app.tecla5 = uibutton(app.TecladoPanel, 'push');
            app.tecla5.ButtonPushedFcn = createCallbackFcn(app, @tecla5_Callback, true);
            app.tecla5.Tag = 'tecla5';
            app.tecla5.Icon = fullfile(pathToMLAPP, 'numero-5.png');
            app.tecla5.Text = '';
            app.tecla6 = uibutton(app.TecladoPanel, 'push');
            app.tecla6.ButtonPushedFcn = createCallbackFcn(app, @tecla6_Callback, true);
            app.tecla6.Tag = 'tecla6';
            app.tecla6.Icon = fullfile(pathToMLAPP, 'seis.png');
            app.tecla6.Text = '';
            app.tecla7 = uibutton(app.TecladoPanel, 'push');
            app.tecla7.ButtonPushedFcn = createCallbackFcn(app, @tecla7_Callback, true);
            app.tecla7.Tag = 'tecla7';
            app.tecla7.Icon = fullfile(pathToMLAPP, 'siete.png');
            app.tecla7.Text = '';
            app.tecla8 = uibutton(app.TecladoPanel, 'push');
            app.tecla8.ButtonPushedFcn = createCallbackFcn(app, @tecla8_Callback, true);
            app.tecla8.Tag = 'tecla8';
            app.tecla8.Icon = fullfile(pathToMLAPP, 'numero-8.png');
            app.tecla8.Text = '';
            app.tecla9 = uibutton(app.TecladoPanel, 'push');
            app.tecla9.ButtonPushedFcn = createCallbackFcn(app, @tecla9_Callback, true);
            app.tecla9.Tag = 'tecla9';
            app.tecla9.Icon = fullfile(pathToMLAPP, 'numero-9.png');
            app.tecla9.Text = '';
            app.teclaAst = uibutton(app.TecladoPanel, 'push');
            app.teclaAst.ButtonPushedFcn = createCallbackFcn(app, @teclaAst_Callback, true);
            app.teclaAst.Tag = 'teclaAst';
            app.teclaAst.Icon = fullfile(pathToMLAPP, 'asteriscos.png');
            app.teclaAst.Text = '';
            app.tecla0 = uibutton(app.TecladoPanel, 'push');
            app.tecla0.ButtonPushedFcn = createCallbackFcn(app, @tecla0_Callback, true);
            app.tecla0.Tag = 'tecla0';
            app.tecla0.Icon = fullfile(pathToMLAPP, 'cero.png');
            app.tecla0.Text = '';
            app.teclaGato = uibutton(app.TecladoPanel, 'push');
            app.teclaGato.ButtonPushedFcn = createCallbackFcn(app, @teclaGato_Callback, true);
            app.teclaGato.Tag = 'teclaGato';
            app.teclaGato.Icon = fullfile(pathToMLAPP, 'hashtag (1).png');
            app.teclaGato.Text = '';
            app.tecla2 = uibutton(app.TecladoPanel, 'push');
            app.tecla2.ButtonPushedFcn = createCallbackFcn(app, @tecla2_Callback, true);
            app.tecla2.Tag = 'tecla2';
            app.tecla2.Icon = fullfile(pathToMLAPP, 'numero-2.png');
            app.tecla2.Text = '';
            app.tecla4 = uibutton(app.TecladoPanel, 'push');
            app.tecla4.ButtonPushedFcn = createCallbackFcn(app, @tecla4_Callback, true);
            app.tecla4.Tag = 'tecla4';
            app.tecla4.Icon = fullfile(pathToMLAPP, 'numero-cuatro.png');
            app.tecla4.Text = '';
            app.llamada_8 = uibutton(app.TecladoPanel, 'push');
            app.llamada_8.ButtonPushedFcn = createCallbackFcn(app, @invita, true);
            app.llamada_8.Tag = 'llamada';
            app.llamada_8.Icon = fullfile(pathToMLAPP, 'telefono-publico-de-monedas.png');
            app.llamada_8.Text = 'Invitacion A Marcar';
            app.OscilogramaPanel = uipanel(app.figure1);
            app.OscilogramaPanel.Title = 'Oscilograma';
            app.oscilograma = uiaxes(app.OscilogramaPanel);
            app.oscilograma.Tag = 'oscilograma';
            app.EspectogramaPanel = uipanel(app.figure1);
            app.EspectogramaPanel.Title = 'Espectograma';
            app.espectograma = uiaxes(app.EspectogramaPanel);
            app.espectograma.Tag = 'espectograma';
            app.MartinMorenoCesarSadrakTelefona2TM9Label = uilabel(app.figure1);
            app.MartinMorenoCesarSadrakTelefona2TM9Label.Text = 'Martin Moreno Cesar Sadrak, Telefonía 2TM9';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = pra1_App_2(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)pra1_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end